classdef cizgesel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        izgeselv12UIFigure  matlab.ui.Figure
        HTML                matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.HTML.HTMLSource = 'cizgesel.html';
            % Uygulamayı tam ekran başlatarak standart Windows çubuğunu gizle
            app.izgeselv12UIFigure.WindowState = 'fullscreen';
            app.izgeselv12UIFigure.Name = 'Çizgesel v1.2 Şablon Sürümü';
            % app.UIFigure.Icon = 'cizgesel.png'; 
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
  
    gelenVeri = app.HTML.Data;
    
    try
        komut = jsondecode(gelenVeri);
        
        % =========================================================
        % 1. SENARYO: DERECE DİZİSİ GİRİLDİYSE (Havel-Hakimi)
        % =========================================================
        if strcmp(komut.islem, 'DereceDizisiCiz') || strcmp(komut.islem, 'HavelHakimiAdimli')
            
            adimliMi = strcmp(komut.islem, 'HavelHakimiAdimli');
            gelenDetay = komut.detay;
            tic
            
            % --- Veri Tipi Kontrolü (Matris mi, Dizi mi?) ---
            if iscell(gelenDetay) || isnumeric(gelenDetay)
                girdiTipi = 'matris';
                A_gelen = gelenDetay;
                if iscell(A_gelen), A_gelen = vertcat(A_gelen{:}); end
                D = sum(double(A_gelen), 2)'; 
                app.HTML.Data = jsonencode(struct('aksiyon', 'log_yaz', 'mesaj', '> MATLAB: Çizim üzerinden dereceler okundu. Analiz başlıyor...'));
                drawnow;
            else
                girdiTipi = 'dizi';
                diziMetni = gelenDetay;
                diziMetni = strrep(diziMetni, '[', ''); 
                diziMetni = strrep(diziMetni, ']', '');
                D = str2num(diziMetni);
            end
                
            if adimliMi
                app.HTML.Data = jsonencode(struct('aksiyon', 'log_yaz', 'mesaj', '> MATLAB: Havel-Hakimi simülasyonu başlatılıyor...', 'timestamp', num2str(rand)));
            else
                app.HTML.Data = jsonencode(struct('aksiyon', 'log_yaz', 'mesaj', '> MATLAB: Dizi anında çiziliyor...', 'timestamp', num2str(rand)));
            end
            
            if isempty(D) || ~isvector(D) || any(mod(D, 1) ~= 0) || any(D < 0)  
                error('Hatalı giriş! Sadece sayılar girin (Örn: 4,3,2,2,1).');
            end
        
            D = sort(D, 'descend'); 
            N = length(D);
            adimSayaci = 1;
            
            % -------------------------------------------------------------
            % [KENDİ HAVEL-HAKİMİ VE BAĞLANTILILIK ALGORİTMANIZI BURAYA YAZIN]
            % Hedef: D dizisini kullanarak N x N boyutlarında bir A matrisi oluşturmak.
            % -------------------------------------------------------------
            
            A = zeros(N, N); % Çizimin çökmemesi için geçici boş matris
            
            gecenSure = toc;
            
            if strcmp(girdiTipi, 'dizi')
                % --- Çizim Paketi Hazırlama ---
                [kaynak, hedef] = find(triu(A) == 1); 
                M = length(kaynak);
                
                elements = struct();
                elements.nodes = cell(N, 1);
                for i = 1:N
                    elements.nodes{i} = struct('data', struct('id', sprintf('n%d', i), 'label', sprintf('v%d', i)));
                end
                
                elements.edges = cell(M, 1);
                for j = 1:M
                    elements.edges{j} = struct('data', struct('id', sprintf('e%d', j), 'source', sprintf('n%d', kaynak(j)), 'target', sprintf('n%d', hedef(j))));
                end
                
                gidenPaket.aksiyon = 'cizge_ciz';
                gidenPaket.elements = elements;
                gidenPaket.matris = num2cell(A, 2); 
                
                if adimliMi
                    gidenPaket.metrik = struct('dugum', N, 'ayrit', M, 'durum', 'Analiz Tamamlandı', 'sure', gecenSure);
                else
                    gidenPaket.metrik = struct('dugum', N, 'ayrit', M, 'durum', 'Dizi Hızlı Çizildi');
                end
                
                app.HTML.Data = jsonencode(gidenPaket);
                drawnow;
            else
                bitisMesaji = sprintf('> Analiz %0.4f saniyede tamamlandı.', gecenSure);
                app.HTML.Data = jsonencode(struct('aksiyon', 'log_yaz', 'mesaj', bitisMesaji));
                drawnow;
            end

        % =========================================================
        % 2. SENARYO: MANUEL MATRİS GİRİLDİYSE (Sadece Render - Kod Silinmedi)
        % =========================================================
        elseif strcmp(komut.islem, 'MatrisDuzenlendi')
            
            A = komut.detay; 
            if iscell(A), try A = vertcat(A{:}); catch, error('Matris formatı HTML''den hatalı geldi.'); end; end
            A = double(A); 
            if size(A, 1) ~= size(A, 2), error('Komşuluk matrisi kare (NxN) olmalıdır!'); end
            
            A = max(A, A'); 
            N = size(A, 1);
            [kaynak, hedef] = find(triu(A) == 1); 
            M = length(kaynak);
            
            app.HTML.Data = jsonencode(struct('aksiyon', 'log_yaz', 'mesaj', '> MATLAB: Manuel matris algılandı. Render ediliyor...', 'timestamp', num2str(rand)));
            drawnow;
            
            elements = struct();
            elements.nodes = cell(N, 1);
            for i = 1:N, elements.nodes{i} = struct('data', struct('id', sprintf('n%d', i), 'label', sprintf('v%d', i))); end
            elements.edges = cell(M, 1);
            for j = 1:M, elements.edges{j} = struct('data', struct('id', sprintf('e%d', j), 'source', sprintf('n%d', kaynak(j)), 'target', sprintf('n%d', hedef(j)))); end
            
            gidenPaket = struct();
            gidenPaket.aksiyon = 'cizge_ciz';
            gidenPaket.elements = elements;
            gidenPaket.matris = num2cell(A, 2); 
            gidenPaket.metrik = struct('dugum', N, 'ayrit', M, 'durum', 'Manuel Matris Çizildi');
            gidenPaket.timestamp = num2str(rand); 
            
            app.HTML.Data = jsonencode(gidenPaket);
            drawnow;
            
        % =========================================================
        % 3. SENARYO: FLEURY ALGORİTMASI
        % =========================================================
        elseif strcmp(komut.islem, 'Fleury')
            
            A = komut.detay;
            if isempty(A), error('Önce bir graf çizdirmelisiniz!'); end
            if iscell(A), try A = vertcat(A{:}); catch, error('Matris okunamadı.'); end; end
            A = double(A);
            N = size(A, 1);
            
            app.HTML.Data = jsonencode(struct('aksiyon', 'log_yaz', 'mesaj', '> MATLAB: Fleury algoritması başlatılıyor...', 'timestamp', num2str(rand)));
            drawnow;
            
            % -------------------------------------------------------------
            % [KENDİ FLEURY ALGORİTMANIZI BURAYA YAZIN]
            % Hedef: euler_yol dizisini doldurmak (Örn: [1, 2, 3, 1]) ve 
            % fleury_basarili boolean (true/false) değişkenini ayarlamak.
            % -------------------------------------------------------------
            
            euler_yol = []; 
            fleury_basarili = false; 
            
            if fleury_basarili
                paket.aksiyon = 'fleury_animasyon';
                paket.yol = euler_yol;
                paket.timestamp = num2str(rand);
                app.HTML.Data = jsonencode(paket);
                drawnow;
            else
                app.HTML.Data = jsonencode(struct('aksiyon', 'log_yaz', 'mesaj', '> Fleury için Euler yolu bulunamadı veya kendi algoritmanızı eklemediniz.')); drawnow;
            end
            
        % =========================================================
        % 4. SENARYO: PENCERE KONTROLLERİ
        % =========================================================
        elseif strcmp(komut.islem, 'PencereKapat')
            delete(app.izgeselv12UIFigure); 
            
        elseif strcmp(komut.islem, 'PencereKucult')
            app.izgeselv12UIFigure.WindowState = 'minimized';

        % =========================================================
        % 5. SENARYO: BAĞLANTILILIK ANALİZİ
        % =========================================================
        elseif strcmp(komut.islem, 'BaglantililikAnalizi')
            
            A = komut.detay;
            if isempty(A), error('Analiz edilecek bir graf bulunamadı!'); end
            if iscell(A), try A = vertcat(A{:}); catch, error('Matris okunamadı.'); end; end
            A = double(A);
            
            app.HTML.Data = jsonencode(struct('aksiyon', 'log_yaz', 'mesaj', '> --- GRAF BAĞLANTILILIK ANALİZİ BAŞLADI ---')); drawnow; pause(0.2);
            
            % -------------------------------------------------------------
            % [KENDİ BAĞLANTILILIK ALGORİTMANIZI BURAYA YAZIN]
            % Hedef: Grafiğin bağlantılı olup olmadığını (K bileşen sayısını vb.) bulmak.
            % -------------------------------------------------------------
            
            app.HTML.Data = jsonencode(struct('aksiyon', 'log_yaz', 'mesaj', '> Kendi Bağlantılılık algoritmanızı kod alanına ekleyin.')); drawnow;

        % =========================================================
        % 6. SENARYO: İKİ PARÇALILIK (BIPARTITE)
        % =========================================================
        elseif strcmp(komut.islem, 'IkiParcaliAnaliz')
            
            A = komut.detay;
            if isempty(A), error('Analiz edilecek bir graf yok!'); end
            if iscell(A), try A = vertcat(A{:}); catch, error('Matris okunamadı.'); end; end
            A = double(A);
            N = size(A, 1);
            
            app.HTML.Data = jsonencode(struct('aksiyon', 'log_yaz', 'mesaj', '> --- İKİ PARÇALILIK (BIPARTITE) ANALİZİ BAŞLADI ---')); drawnow; pause(0.2);
            
            % -------------------------------------------------------------
            % [KENDİ İKİ PARÇALILIK (BIPARTITE) ALGORİTMANIZI BURAYA YAZIN]
            % Hedef: iki_parcali_mi boolean'ını belirlemek ve eğer true ise 
            % kume1_listesi ve kume2_listesi dizilerini doldurmak.
            % -------------------------------------------------------------
            
            iki_parcali_mi = true;
            kume1_listesi = [];
            kume2_listesi = [];
            hata_dugumu1 = 1;
            hata_dugumu2 = 2;
            
            paket = struct();
            paket.aksiyon = 'iki_parcali_boya';
            paket.basarili = iki_parcali_mi;
            paket.timestamp = num2str(rand);
            
            if iki_parcali_mi
                paket.kume1 = kume1_listesi;
                paket.kume2 = kume2_listesi;
            else
                paket.hata1 = hata_dugumu1;
                paket.hata2 = hata_dugumu2;
            end
            app.HTML.Data = jsonencode(paket);
            drawnow;
        
        % =========================================================
        % 7. SENARYO: EN UZUN YOL BULMA
        % =========================================================
        elseif strcmp(komut.islem, 'EnUzunYolBul')
            
            A = komut.detay;
            if isempty(A), error('Analiz edilecek bir graf yok!'); end
            if iscell(A), try A = vertcat(A{:}); catch, error('Matris okunamadı.'); end; end
            A = double(A);
            
            app.HTML.Data = jsonencode(struct('aksiyon', 'log_yaz', 'mesaj', '> --- EN UZUN YOL (ZİNCİR) ANALİZİ BAŞLADI ---')); drawnow; pause(0.2);
            
            tic;
            
            % -------------------------------------------------------------
            % [KENDİ EN UZUN YOL ALGORİTMANIZI BURAYA YAZIN]
            % Hedef: uzun_yol isimli listeyi sıralı düğüm indeksleriyle doldurmak.
            % -------------------------------------------------------------
            
            uzun_yol = []; % Çökmemesi için boş
            
            sure = toc;
            
            paket = struct();
            paket.aksiyon = 'yol_boya';
            paket.yol = uzun_yol;
            paket.timestamp = num2str(rand);
            
            app.HTML.Data = jsonencode(paket);
            drawnow;

        % =========================================================
        % 8. SENARYO: MAKSİMUM KLİK BULMA
        % =========================================================
        elseif strcmp(komut.islem, 'MaksKlikBul')
            
            A = komut.detay;
            if isempty(A), error('Analiz edilecek bir graf yok!'); end
            if iscell(A), try A = vertcat(A{:}); catch, error('Matris okunamadı.'); end; end
            A = double(A);
            N = size(A, 1);
            
            app.HTML.Data = jsonencode(struct('aksiyon', 'log_yaz', 'mesaj', '> --- MAKSİMUM KLİK ANALİZİ BAŞLADI ---')); drawnow; pause(0.2);
            
            tic;
            
            % -------------------------------------------------------------
            % [KENDİ MAKSİMUM KLİK ALGORİTMANIZI BURAYA YAZIN]
            % Hedef: maks_klik isimli diziyi bulduğunuz en büyük grubun düğümleriyle doldurmak.
            % -------------------------------------------------------------
            
            maks_klik = []; % Çökmemesi için boş dizi
            
            sure = toc;
            
            paket = struct();
            paket.aksiyon = 'klik_boya';
            paket.klik = maks_klik;
            paket.timestamp = num2str(rand);
            
            app.HTML.Data = jsonencode(paket);
            drawnow;
            
        % =========================================================
        % 9. SENARYO: MAKSİMUM BAĞIMSIZ KÜME BULMA
        % =========================================================
        elseif strcmp(komut.islem, 'MaksBagimsizKume')
            
            A = komut.detay;
            if isempty(A), error('Analiz edilecek bir graf yok!'); end
            if iscell(A), try A = vertcat(A{:}); catch, error('Matris okunamadı.'); end; end
            A = double(A);
            N = size(A, 1);
            
            app.HTML.Data = jsonencode(struct('aksiyon', 'log_yaz', 'mesaj', '> --- MAKS. BAĞIMSIZ KÜME ANALİZİ BAŞLADI ---')); drawnow; pause(0.2);
            
            tic;
            
            % -------------------------------------------------------------
            % [KENDİ MAKSİMUM BAĞIMSIZ KÜME ALGORİTMANIZI BURAYA YAZIN]
            % Hedef: bagimsiz_kume isimli listeyi birbiriyle komşu olmayan en büyük düğüm grubuyla doldurmak.
            % -------------------------------------------------------------
            
            bagimsiz_kume = []; % Çökmemesi için boş dizi
            
            sure = toc;
            
            paket = struct();
            paket.aksiyon = 'bagimsiz_kume_boya';
            paket.kume = bagimsiz_kume;
            paket.timestamp = num2str(rand);
            
            app.HTML.Data = jsonencode(paket);
            drawnow; 
            
        end
        
    catch ME
        hataMesaji = ['> HATA: ', ME.message];
        disp('=== MATLAB ALGORİTMA HATASI ===');
        disp(ME.message);
        
        hataPaketi.aksiyon = 'log_yaz';
        hataPaketi.mesaj = hataMesaji;
        hataPaketi.timestamp = num2str(rand); 
        app.HTML.Data = jsonencode(hataPaketi);
        drawnow;
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.izgeselv12UIFigure = uifigure('Visible', 'off');
            app.izgeselv12UIFigure.Name = 'Çizgesel v1.2';
            app.HTML = uihtml(app.izgeselv12UIFigure);
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            % Show the figure after all components are created
            app.izgeselv12UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cizgesel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.izgeselv12UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.izgeselv12UIFigure)
        end
    end
end